classdef JoshTutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        YearSliderLabel     matlab.ui.control.Label
        YearSlider          matlab.ui.control.Slider
        YearEditFieldLabel  matlab.ui.control.Label
        YearEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Data % values from historical data
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            file_name = 'historicaldata.txt';
            fid = fopen(file_name);
            data = []; %blank array to start filling
            header = []; %empty header array
            lines = fgetl(fid); % get one line, to start loop
            while(~isnumeric(lines))
                data = [data; str2num(lines)];
                if(isempty(data))
                    header = [header, lines];
                end
                lines = fgetl(fid);
            end
            app.Data = data;
            year = int8(app.YearSlider.Value - 1959); %convert to index
            plot(app.UIAxes, data(1:year,1), data(1:year,2));
            app.UIAxes.XLim = [data(1,1) data(end,1)];
            app.UIAxes.YLim = [(data(1,2)-.5) (data(end,2)+.5)];
            app.YearEditField.Value = app.YearSlider.Value;
        end

        % Value changed function: YearSlider
        function YearSliderValueChanged(app, event)
            value = app.YearSlider.Value;
            year = int8(app.YearSlider.Value - 1959); %convert to index
            plot(app.UIAxes, app.Data(1:year,1), app.Data(1:year,2));
            app.YearEditField.Value = app.YearSlider.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Emmision Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.YearSliderLabel = uilabel(app.UIFigure);
            app.YearSliderLabel.Text = 'Year';
            app.YearSlider = uislider(app.UIFigure);
            app.YearSlider.Limits = [1959 2017];
            app.YearSlider.MajorTicks = [1959 1988 2017];
            app.YearSlider.ValueChangedFcn = createCallbackFcn(app, @YearSliderValueChanged, true);
            app.YearSlider.Value = 1974;
            app.YearEditFieldLabel = uilabel(app.UIFigure);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = JoshTutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end