classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        dataInputTab                    matlab.ui.container.TabGroup
        Files                           matlab.ui.container.Tab
        ConstraintsTextFile             matlab.ui.control.EditField
        StrategyTextFile                matlab.ui.control.EditField
        ConstraintsTextFileLabel        matlab.ui.control.Label
        StrategyTextFileEditFieldLabel  matlab.ui.control.Label
        ErrorText                       matlab.ui.control.TextArea
        WedgesTextLamp                  matlab.ui.control.Lamp
        ConstraintsTextLamp             matlab.ui.control.Lamp
        StrategyTextLamp                matlab.ui.control.Lamp
        wedgesBrowse                    matlab.ui.control.Button
        constraintsBrowse               matlab.ui.control.Button
        strategyBrowse                  matlab.ui.control.Button
        titleLabel                      matlab.ui.control.Label
        descriptionLabel                matlab.ui.control.Label
        WedgesTextFileEditFieldLabel    matlab.ui.control.Label
        WedgesTextFile                  matlab.ui.control.EditField
        EmissionsGraphTab               matlab.ui.container.Tab
        TotalCostEditFieldLabel_2       matlab.ui.control.Label
        tCost                           matlab.ui.control.NumericEditField
        TotalWedgesUsedEditFieldLabel   matlab.ui.control.Label
        tWedges                         matlab.ui.control.NumericEditField
        NumberofWedgestoFlatlineatDropDownLabel  matlab.ui.control.Label
        FlatlineYear                    matlab.ui.control.DropDown
        NumofWedgesFlatline             matlab.ui.control.NumericEditField
        inStartYearLabel                matlab.ui.control.Label
        StartYearEditField              matlab.ui.control.NumericEditField
        EmissionsGraph                  matlab.ui.control.UIAxes
        CO2WarmingTab                   matlab.ui.container.Tab
        GraphSwitch                     matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        LambdaValue                     matlab.ui.control.Slider
        CO2WarmingGraph                 matlab.ui.control.UIAxes
        WedgesByTable                   matlab.ui.container.Tab
        WedgeTable                      matlab.ui.control.Table
        wAddAbove                       matlab.ui.control.Button
        wSave                           matlab.ui.control.Button
        wDelete                         matlab.ui.control.Button
        wAddBelow                       matlab.ui.control.Button
        AddWedge                        matlab.ui.control.Button
        StartinginYearEditFieldLabel    matlab.ui.control.Label
        AWYearStart                     matlab.ui.control.NumericEditField
        StrategyByTable                 matlab.ui.container.Tab
        StratTable                      matlab.ui.control.Table
        sAddAbove                       matlab.ui.control.Button
        sAddBelow                       matlab.ui.control.Button
        sDelete                         matlab.ui.control.Button
        sSave                           matlab.ui.control.Button
        ConstraintsTable                matlab.ui.control.Table
        cAddAbove                       matlab.ui.control.Button
        cAddBelow                       matlab.ui.control.Button
        cDelete                         matlab.ui.control.Button
        cSave                           matlab.ui.control.Button
        ConstraintsLabel                matlab.ui.control.Label
        StrategyLabel                   matlab.ui.control.Label
        GraphButton                     matlab.ui.control.Button
        sortStrat                       matlab.ui.control.Button
        plsSelect                       matlab.ui.control.Label
        wInfo                           matlab.ui.control.TextArea
        OuputTable                      matlab.ui.container.Tab
        OutTable                        matlab.ui.control.Table
        PlsLoadLabel                    matlab.ui.control.Label
        oSave                           matlab.ui.control.Button
    end


    properties (Access = private)
%         wedgeHeader %cell of each name of wedge struct field
        headerz = {strsplit('ID;Category;StrategyName;Sector;Description;Needs;Challenges;Cost',';'); {'Sector', 'Constraints'};{'ID','YearStart'}}
            %make list of all the header files, for easier access, and printing to file
        fileCancel = 'File selection cancelled.'
%         allFilled = [0 0 0]; % are elements full?
        selectedInd % last selected indices on tables
        dim = [8 3 2 6]; %dimensions of cells [wedges, constraints, strat, ouput]
        CO2_vals; %values of CO2 per year
        emisOTime %emissions over time, updated with each new wedge
        lambda; %calculation for warming
        anno = [0 0]; % Is the aparment on fire?  Will we even wake up? How to disable the graph button properly. Zero means it's ok
    end

    properties (Access = public)
        wedges = struct; % struct full of wedges
        constraints = struct; % struct with portfolio constraints
        strat = struct; %struct with all strategy info
        usedConst = struct; %struct of used structs
        historical
        files = {'','','', ''}; %file locations

    end

    methods (Access = public)
    
        function [] = readWedges(app, file_name)
            %readWedges  Checks if it is appropriate file
            %get wedges info into struct
            %   Each wedge has it's ID as it's position in struct, as well as ID
            %   each element of header is in field
            fid = fopen(file_name); 
            app.wedges = struct; %re - create
            app.files{1} = justFileName(app,file_name);
            if(fid == -1)
                internalError(app, 'Wedges', 'file and path are');
                return
            end
            line = fgetl(fid);
            if strcmp(line,'ID;Category;StrategyName;Sector;Description;Needs;Challenges;Cost') == 0  %Checks that it is correct wedges file
                internalError(app, 'Wedges', 'file header formatting is')
                return
            end
            header = strsplit(line, ';'); %split at semicolons
            app.wedges = struct(); %make struct
            for indField = 1:numel(header)
                app.wedges.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line, ';');
                if(numel(parts) ~= numel(header)) %wrong number of items
                    internalError(app, 'Wedges', 'file data formatting is');
                    return
                end
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    end %if
                    app.wedges(parts{1}).(header{indF})= parts{indF};
                        %add each part into the struct, ID is also it's position
        
                end %for loop
                line = fgetl(fid);
            end% while loop
            app.ErrorText.Value = ''; %reset to blank
            fclose all; %close files... a bit late, but not never!
        end
        
        function [] = readConstraints(app, file_name)
            %readConstraints Checks if it is appropriate file
            %Put 'em in a struct
            %   each sector is a field, with limits as double value
            fid = fopen(file_name);%open file
            app.constraints = struct; %re - create
            app.files{2} = justFileName(app,file_name);
            if(fid == -1)
                internalError(app, 'Constraints', 'file and path are')
                return
            end
            line = fgetl(fid);
            if strcmp(line,'Sector Constraints') == 0  %Checks that it is correct wedges file
                internalError(app, 'Constraints', 'file header formatting is');
                return
            end
            header = strsplit(line); %split at semicolons
            app.constraints = struct(); %make struct
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line);
                if(numel(parts) ~= numel(header)) %wrong number of items
                    internalError(app, 'Constraints', 'file data formatting is');
                    return
                end
                parts{2} = str2double(parts{2}); %conver to doubles
                app.constraints.(parts{1})= parts{2};
                    %add each part into the struct, ID is also it's position
                line = fgetl(fid);
            end% while loop
            app.ErrorText.Value = ''; %reset to blank
            fclose all; %close files... a bit late, but not never!
        end
        
        
        function [] = graphHistoricalData(app)
            x = app.historical(:,1); %year
            y =  app.historical(:,2); %GtC/year
            x1 = [1959:1:2068];
            hold(app.EmissionsGraph, 'off'); %delete the graphs
            myPrediction = @(t) y(end) * 2.^((t-2017)/50); %formula for Prediction line
            app.EmissionsGraph.XLim = [1959 2068];
            app.EmissionsGraph.YLim = [0 myPrediction(x1(end))];
            title(app.EmissionsGraph, 'Emissions Graph');
            xlabel(app.EmissionsGraph, 'Year');
            ylabel(app.EmissionsGraph, 'Billions of Tons Carbon Emitted Per Year');
            try 
                FlatLineYear = app.strat(1).YearStart;
            catch
                FlatLineYear = 2018; 
            end
            if(FlatLineYear < 2018 || FlatLineYear > 2068) %out of bounds
                FlatLineYear = 2018; %defualt
            end %if flatline out of bounds
            FlatLine = myPrediction(FlatLineYear);
            myX = [2017:1:2068]; %time values for which I will plot prediction points
            plot(app.EmissionsGraph, x, y, 'k-', myX, myPrediction(myX),'b-', [FlatLineYear:1:2068], linspace(FlatLine,FlatLine,2069-FlatLineYear), 'r-');
            hold(app.EmissionsGraph, 'on'); %holds EmissionsGraph
            app.emisOTime = app.historical(42:59,2)'; %historical data 2000-2017
            myWedgeY = myPrediction(myX);
            app.emisOTime = [app.emisOTime myWedgeY(2:end)]; %add my prediction
            if size(app.strat) == [0 0]
                return
            end
            myWedgeX = myX;
            counter = numel(app.strat); %number of elements in each field of app.strat
            myFunc = myPrediction;
            myWedgeArray = []; %Will track each fill command, which is used for legend
            myWedgeName = {}; %Will track each wedge name, which is used for legend
            for j = 1:counter
                try 
                    YearStart = app.strat(j).YearStart; 
                    ID = app.strat(j).ID;
                catch
                    break
                end
                if(isempty(YearStart) || isempty(ID))
                    break; %reee, won't graph last wedge
                end %if
                if(YearStart < 2018 || YearStart > 2068) %out of bounds
                    uialert(app.UIFigure, sprintf('Check that wedge %d starts between 2018 and 2068', ID),'Start Year');
                    break;
                end %if out of bounds
                %This method ONLY works if the yearstarts are plotted in order
                SavedX = myWedgeX; %stores your previous X values...from your previous YearStart to 2068
                SavedY = myWedgeY;
                if ischar(YearStart) 
                    YearStart = str2num(YearStart);
                end
                if ischar(ID)
                    ID = str2num(ID);
                end
                if app.ColorFill(ID) == 0 
                    break;
                end
                myWedgeX = [YearStart:1:2068]; %Creates your new X values from your current Yearstart to 2068
                myFunc = app.oneWedge(myFunc, YearStart); %Gets Y-values for previous wedge curve starting from YearStart
                myWedgeY = myFunc(myWedgeX);
                plot(app.EmissionsGraph, myWedgeX, myWedgeY, 'm-'); %plots wedge lines
                app.emisOTime((YearStart-1999):end) = myWedgeY; %include new data
                mynewWedgeY = SavedY(YearStart-SavedX(1)+1:end); %Needed for fill
                myWedgeArray(j) = fill(app.EmissionsGraph, [myWedgeX fliplr(myWedgeX)], [myWedgeY fliplr(mynewWedgeY)], app.ColorFill(ID));
                myWedgeName{j} = sprintf('Wedge %d', ID);
            end
            if isempty(myWedgeArray) == 0  %creates legend as long as one fill has occurred
                lgd = legend(app.EmissionsGraph, myWedgeArray, myWedgeName, 'Location', 'northwest'); 
            else
                legend(app.EmissionsGraph,'off');%remove the legend
            end
        end

        function [] = readHistoricalData(app)
            % Checks if it is appropriate file
            % Put 'em in a struct
            fid = fopen('historicaldata.txt');%open file
            app.files{4} = 'historicaldata.txt';
            if(fid == -1)
                internalError(app, 'Historical Data','file and path are');
                return
            end
            line = fgetl(fid);
            if strcmp(line,  'Year	fossil fuel and industry GtC/yr') == 0  %Checks that it is correct wedges file
                internalError(app, 'Historical Data','file header formatting is');
                return
            end
            app.historical = zeros(59,2);
            for i = 1:59 %Creates an array of values from the historical text file
                line = fgetl(fid);
                line = strsplit(line, '	');
                app.historical(i,1) = str2num(line{1}); %Puts the years into the matrix
                app.historical(i,2) = str2num(line{2}); %Puts the GtC/year into the matrix
            end
%             app.EmissionsGraphTable.Data = num2cell(app.historical); %puts it into table on HistoricalData Tab
                %We think this is the old historical data table
        end
        function [] = readStrat(app, file_name)
            fid = fopen(file_name);%open file
            app.strat = struct; %re - create
            app.files{3} = justFileName(app,file_name);
            if(fid == -1)
                internalError(app, 'Strategy','file and path are');
                return
            end
            line = fgetl(fid);
            if strcmp(line,'ID YearStart') == 0  %Checks that it is correct wedges file
                internalError(app, 'Strategy','file header formatting is');
                return
            end
            header = strsplit(line); %split at semicolons
            app.strat = struct(); %make struct
            for indField = 1:numel(header)
                app.strat.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            indSpot = 1; %increment through each spot
            while(~isnumeric(line))
                parts = strsplit(line);
                if(numel(parts) ~= numel(header)) %wrong number of items
                    internalError(app, 'Strategy', 'file data formatting is');
                    return
                end
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    else %there are text things, and there shouldn't be
                        internalError(app, 'Strategy', 'file data formatting is');
                        return
                    end %if
                    app.strat(indSpot).(header{indF})= parts{indF};
                    %add each part into the struct, ID is also it's position
                    
                end %for loop
                line = fgetl(fid);
                indSpot = indSpot + 1; %next spot
            end% while loop
            app.ErrorText.Value = ''; %reset to blank
            fclose all; %close files... a bit late, but not never!
        end
    end
  

    methods (Access = private)
    
        function [] = toggleInputFields(app)
            %toggleInputFields Startup cleanup
            %   See if structs are filled, and based on that
            %   disable input fields to keep it clean on start screen
            allFilled = [0 0 0]; %keep track of what is already stored
            outputTable(app); %update output table, does check inside
            FlatlinePrediction(app);
            checkConstraints(app); %run it early, run it often
            if(isfield(app.wedges, 'ID')) %wedges is filled
                app.WedgesTextFile.Enable = 'off';
                app.WedgesTextFile.Value = app.files{1};
                app.WedgesTextLamp.Color = 'green';
                app.WedgesTextFile.FontColor = 'black';
                allFilled(1) = 1;
                unFormData = struct2cell(app.wedges); %unformatted data
                flipData(:,:) = unFormData(:,1,:);
                app.WedgeTable.Data = flipData';
            else
                app.WedgesTextFile.Enable = 'on';
                app.WedgesTextFile.Value = app.files{1};
                app.WedgesTextLamp.Color = 'red';
                app.WedgesTextFile.FontColor = 'red';
                allFilled(1) = 0;
            end
            if(~isempty(fieldnames(app.constraints)))
                %fields exist, so we can move from here
                app.ConstraintsTextFile.Enable = 'off';
                app.ConstraintsTextFile.Value = app.files{2};
                app.ConstraintsTextLamp.Color = 'green';
                app.ConstraintsTextFile.FontColor = 'black';
                allFilled(2) = 1;
                unFormData = horzcat(fieldnames(app.constraints), ... 
                    struct2cell(app.constraints),struct2cell(app.usedConst));%Next two lines puts data from app.constraints into table
                app.ConstraintsTable.Data = unFormData;
            else
                app.ConstraintsTextFile.Enable = 'on';
                app.ConstraintsTextFile.Value = app.files{2};
                app.ConstraintsTextLamp.Color = 'red';
                app.ConstraintsTextFile.FontColor = 'red';
                allFilled(2) = 0;
            end
            if(isfield(app.strat, 'ID')) %strat is filled
                app.StrategyTextFile.Enable = 'off';
                app.StrategyTextFile.Value = app.files{3};
                app.StrategyTextLamp.Color = 'green';
                app.StrategyTextFile.FontColor = 'black';
                allFilled(3) = 1;
                unFormData = struct2cell(app.strat); %Next three lines puts data from app.strat into table
                flipStratData(:,:) = unFormData(:,1,:);
                app.StratTable.Data = flipStratData';
            else
                app.StrategyTextFile.Enable = 'on';
                app.StrategyTextFile.Value = app.files{3};
                app.StrategyTextLamp.Color = 'red';
                app.StrategyTextFile.FontColor = 'red';
                allFilled(3) = 0;
            end


            %%%%% Let's not have this little thing.
%             if(all(allFilled == 1)) % we have all text files
%                 app.ErrorText.Value = 'All files entered.  Review the data on the table tab for each file, and when ready, press OK to continue.';
%                 app.ErrorText.BackgroundColor = 'green';
%                 app.ErrorText.FontColor = 'black';
%             elseif(all(allFilled(1:2) == 1)) %we have wedges and constraint
%                 app.ErrorText.Value = 'Wedges and Constraint files entered.  Review the data on the table tab for each file, and when ready, press OK to continue.  Inside, you can choose your strategy with live feedback.';
%                 app.ErrorText.BackgroundColor = 'green';
%                 app.ErrorText.FontColor = 'black';
%             end %if
            
        end
        
        function file = justFileName(app, filepath)
        %justFileName Split to get just name of file
            parts = strsplit(filepath,'/');
            file = parts{end}; %grab last element, the filename
        end
        
        function [] = internalError(app, type, specError)
        %internalError Display errors to clean up code
        %   type is Wedges, Strategy, or Constraints, and automates display
        %   specError is a char that will be sprintf'd into it
        %   Each line sets a formatted error message, sets the field text as red,
        %       and empties the struct so it reads as blank
        
        fileError = 'Error in %s Text File, "%s".  Please check that %s correct.'; 
        %file type, filename, specific error
        
        app.ErrorText.FontColor = 'red'; %set error text as red
        if(strcmp(type, 'Wedges'))
            app.ErrorText.Value = sprintf(fileError, 'Wedges', app.files{1}, specError);
            app.WedgesTextFile.FontColor = 'red';
            app.wedges = struct; %recreate so empty
        elseif(strcmp(type,'Constraints'))
            app.ErrorText.Value = sprintf(fileError, 'Constraints', app.files{2}, specError);
            app.ConstraintsTextFile.FontColor = 'red';
            app.constraints = struct; %recreate so empty
        elseif(strcmp(type,'Strategy'))
            app.ErrorText.Value = sprintf(fileError, 'Strategy', app.files{3}, specError);
            app.StrategyTextFile.FontColor = 'red';
            app.strat = struct; %recreate so empty
        end
        end
        
        function [] = wedgesTableID(app)
        %wedgesTableID reorder table ID values
            data = app.WedgeTable.Data; %get data
            dims = size(data,1); %how many numbers?
            for indID = 1:dims
                data{indID,1} = indID;
            end
            app.WedgeTable.Data = data;
        end
        
        function [newFunc] = oneWedge(app, func,startYear)
        %oneWedge Graph one wedge
        %   func is function handle of last line
        %   startYear is when the wedge is started
        %   color is what color to make the wedge
        
        %   Basic idea: intake function, find value at start year, and +50 years.
        %   From there, do a linreg on exponential function and create new
        %   exponential function.  Then use fill from startYear to end and enclose
        %   the wedge from the above function and the current one
        
            endYear = 2068; %make it more adjustable
            boundar(1,:) = [startYear,startYear+50];
            boundar(2,:) = func(boundar(1,:)); % x and y bounds
            if(boundar(2,2) > boundar(2,1)) %above, so use exp func
                A = [1 0; 1 50]; %A is basically constant
                b = log(boundar(2,:)-[0,1])'; %log of y, with second value being one less
                c = A\b; %lin reg
                newFunc = @(t) boundar(2,1)*exp(c(2)*(t-startYear)); %define
            else % below or flatline, so gotta be straight
                newFunc = @(t) (((boundar(2,2)-1)-boundar(2,1))/50)*(t- ...
                    startYear)+ boundar(2,1); %define linear function
            end %if statement
        end

        function [color] = ColorFill(app, ID)
            try
                value = app.wedges(ID).Category;
            catch
                %WedgesLoad = uifigure; %updated to display on same window
                uialert(app.UIFigure, 'Please load in Wedges File', 'Wedges File'); %Won't let you plot without loading in wedges file
                color = 0;
                return
            end
            if strcmp(value, 'Efficiency and Conservation') %if statement checks for what color to make fill
                color = 'y';
            elseif strcmp(value, 'Fossil Fuel')
                color = 'b';
            elseif strcmp(value, 'Nuclear')
                color = 'r';
            elseif strcmp(value, 'Renewables and Biostorage')
                color = 'g';
            else
                uialert(app.UIFigure, 'Invalid Category', 'Wedges File');
                color = 0; % bad category
            end
        end
        
        function [] = table2structi(app,table)
        %table2structi The i is so it's different than a built in
        %   table is char of which table it is
        if(strcmp(table,'Wedges'))
            data = app.WedgeTable.Data; %get data
            for indD = 1:size(data,1) %how many items do we have?
                if(isempty(data{indD,8}))
                    data{indD,8} = ''; %blank character vector
                end %if
                if(ischar(data{indD,8}))
                    data{indD,8} = str2num(data{indD,8}); %convert to double vals
                end %if its char
            end %for
            expData(1,:,:) = data;
            app.wedges = cell2struct(expData, app.headerz{1},3);
            app.files{1} = 'From Table';
        elseif(strcmp(table,'Constraints'))
            data = app.ConstraintsTable.Data; %get data
            app.constraints = struct; %clear it out, we have data in the table
            for indS = 1:size(data,1) %through each thing
                if(isempty(data{indS,1}))
                    data{indS,1} = 'Sector'; %placeholder value
                end %if no sector name
                if(isempty(data{indS,2}))
                    app.constraints.(data{indS,1}) = ''; %make it blank
                end %if no num value
                if(ischar(data{indS,2}))
                    app.constraints.(data{indS,1}) = str2num(data{indS,2}); %fill struct
                else
                    app.constraints.(data{indS,1}) = data{indS,2}; %fill struct
                end %if its char
            end %for loop
%             checkConstraints(app); %check again, but maybe not rn
            app.files{2} = 'From Table';
        elseif(strcmp(table,'Strategy'))
            data = app.StratTable.Data; %get data
            for indC = 1:numel(data)
                if(isempty(data{indC}))
                    data{indC} = ''; %give it blank char vect
                end %if empty
                if(~isnumeric(data{indC}))
                    data{indC} = str2num(data{indC}); %convert everything to double
                end %if num
            end %for
            expData(1,:,:) = data; %flip it around
            app.strat = cell2struct(expData, app.headerz{3},3); %put into struct
            app.files{3} = 'From Table';
        end %if
        %toggleInputFields(app); %put it here to shorten the code...Nico commented it out for now
        end
        
        function [] = save2File(app, table)
        %save2File Save the table to a text file to share
        %   table is char of which table
        [f,p] = uiputfile({'*.txt','Text Files (.txt)';'*.*','All Files'});
        if(f == 0)%file selection cancelled
            return;
        end
        filepath = [p f];
        fid = fopen(filepath, 'w'); %create file 
        if(fid == -1)
%             internalError(app, '
            uialert(app.UIFigure, 'Invalid file name/location', 'Save to File');
            return;
        end
        if(strcmp(table,'Wedges'))
            data = app.WedgeTable.Data; %get data
            fprintf(fid, 'ID;Category;StrategyName;Sector;Description;Needs;Challenges;Cost\n'); %header
            for indRow = 1:size(data,1)
                for indCol = 1:size(data,2)
                    if(isnumeric(data{indRow,indCol}))
                        data{indRow,indCol} = sprintf('%d',data{indRow,indCol});
                    end %if
                    fprintf(fid, data{indRow,indCol});
                    if(indCol ~= app.dim(1)) %last thing, no semicolon
                        fprintf(fid, ';');
                    end %if
                end %col
                fprintf(fid, '\n');
            end %rows
        elseif(strcmp(table,'Constraints'))
            data = app.ConstraintsTable.Data; %get data
            data = data(:,1:2); %don't save num used
            fprintf(fid, 'Sector Constraints\n'); %header
            for indRow = 1:size(data,1)
                for indCol = 1:size(data,2)
                    if(isnumeric(data{indRow,indCol}))
                        data{indRow,indCol} = sprintf('%d',data{indRow,indCol});
                    end %if
                    fprintf(fid, data{indRow,indCol});
                    if(indCol ~= app.dim(2)-1) %last thing, no space
                        fprintf(fid, ' ');
                    end %if
                end %col
                fprintf(fid, '\n');
            end %rows
        elseif(strcmp(table,'Strategy'))
            data = app.StratTable.Data; %get data
            fprintf(fid, 'ID YearStart\n'); %header
            for indRow = 1:size(data,1)
                for indCol = 1:size(data,2)
                    if(isnumeric(data{indRow,indCol}))
                        data{indRow,indCol} = sprintf('%d',data{indRow,indCol});
                    end %if
                    fprintf(fid, data{indRow,indCol});
                    if(indCol ~= app.dim(3)) %last thing, no space
                        fprintf(fid, ' ');
                    end %if
                end %col
                fprintf(fid, '\n');
            end %rows
        elseif(strcmp(table, 'Output'))
            data = app.OutTable.Data; %get data
            fprintf(fid, 'ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges\n'); %header
            for indRow = 1:size(data,1)
                for indCol = 1:size(data,2)
                    if(isnumeric(data{indRow,indCol}))
                        data{indRow,indCol} = sprintf('%d',data{indRow,indCol});
                    end %if
                    fprintf(fid, data{indRow,indCol});
                    if(indCol ~= app.dim(4)) %last thing, no semicolon
                        fprintf(fid, ';'); %uses ; for output
                    end %if
                end %col
                fprintf(fid, '\n');
            end %rows
        end %if
        fclose(fid); %close it up
        end
        
        function [] = outputTable(app)
        %outputTable Create output data based on strategy
        %   ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges
        %   10;Wind Electricity;Electricity;2;4;Local opposition
        
        %   Which data structure to count number of times wedgeIDs appear
        
        if(~isfield(app.wedges, 'ID') || ~isfield(app.strat, 'ID')) %Checks if either is empty
            app.PlsLoadLabel.Visible = 'on';
            return; %GTFO before we hit errors
        end
        if(numel(app.strat) == 1) %just one thing, could be empty
            if(isempty(app.strat.ID))
                app.PlsLoadLabel.Visible = 'on';
                return; %no actual strat, REEEEE
            end %if empty
        end %if one thingy
        app.PlsLoadLabel.Visible = 'off'; %make it disappear
        numUsed = numel(app.strat); %how many wedges are used
        try 
            list = [app.strat(1).ID; 1]; %intialize with one strat thingy
            if(numel(list) < 2) %too few elements
                return;
            end
        catch 
            return
        end
        for indIt = 2:numUsed %loop through every wedge, start at 2
            if(~isempty(app.strat(indIt).ID))
                indices = list(1,:) == app.strat(indIt).ID; %where does it appear?
                if(any(indices)) %one value is nonzero
                    list(2,indices) = list(2,indices) + 1; %increment
                elseif(all(indices == 0)) %the ID doesn't show up
                    list([1 2], end+1) = [app.strat(indIt).ID 1]; %start count
                end % if indices
            end %if empty
        end %for loop
        %sort the list
        swaps = 1;
        while swaps == 1 %while we have swappies
            swaps = 0; %set it as zero, check at end of loop
            for indS = 1:size(list,2)-1
                if(list(1,indS) > list(1,indS+1))
                    tempL = list(:,indS+1); %temp container
                    list(:,indS+1) = list(:,indS); %move it over
                    list(:,indS) = tempL; %back into place
                    swaps = 1; %reset counter
                end %if
            end %for loop
        end %sorting while loop
        %Create the data table
        if(size(app.wedges,2) < list(1,end)) %higher strat than wedges`
            app.PlsLoadLabel.Visible = 'on'; %make it reappear
            return
        end %if
        Data = cell(size(list,2),6); %preallocate
        for indR = 1: size(list,2)
            tempID = list(1,indR); %ID of current item
            Data{indR,1} = tempID;
            Data{indR,2} = app.wedges(tempID).StrategyName; %I KEEP ON ERRORING HERE> WE NEED TO TALK ABOUT HOW TO FORMAT THESE LINES
            Data{indR,3} = app.wedges(tempID).Sector;
            Data{indR,4} = list(2,indR); % total count from bottom row
            Data{indR,5} = list(2,indR) * app.wedges(tempID).Cost; %num used * ind cost
            Data{indR,6} = app.wedges(tempID).Challenges; 
        end %for filling data table
        app.OutTable.Data = Data; %fill the data
        
        % for total cost and total num wedges
        tCosti = 0;
        tWedgi = 0;
        for indSums = 1:size(Data,1) %go through each row
            if(~isempty(Data{indSums,5})) %if it's empty, don't do the thing
                tCosti = tCosti + Data{indSums,5}; %sum costs
            end %if 
            tWedgi = tWedgi + Data{indSums,4}; % sum num used
        end %for loopp for totaling
        app.tCost.Value = tCosti; %display
        app.tWedges.Value = tWedgi; %display
%         checkConstraints(app); %check, debugging rn, but not now now
        end

        function [] = graphCO2emissions(app)
           %calculate and graph CO2 in atmosphere
            app.CO2WarmingGraph.XLim = [2000 2068];
            title(app.CO2WarmingGraph, 'CO2 Concentrations ');
            ylabel(app.CO2WarmingGraph, 'GtC in Atmosphere');
            xlabel(app.CO2WarmingGraph, 'Year');
            xlim(app.CO2WarmingGraph, [2000, 2069]);
            ylim(app.CO2WarmingGraph, [750, 1240]);
            [m,~] = size(app.emisOTime);
            x = 2000:1:2068;
            app.CO2_vals = zeros([1,m]); %populate CO2 values with 0's
            app.CO2_vals(1) = 800;
            for t = 2:69
                app.CO2_vals(t) = app.CO2_vals(t-1) + 0.5*app.emisOTime(t-1); %CO2 calculations
            end
            plot(app.CO2WarmingGraph, x, app.CO2_vals, '-k');
        end
        
        function [] = checkConstraints(app)
            %checkConstraints See if we violate constraints
            %   row striping nx3 ~ each row is RGB val
            
            names = fieldnames(app.constraints); %get the name
            vals = num2cell(zeros(1,numel(names))); %all zeros
            app.usedConst = cell2struct(vals,names,2);
            if(~isfield(app.wedges, 'ID') || ~isfield(app.strat, 'ID')) %no wedges/strat
                return; %no wedges entered, can't check further
            end %if we have constraints
            for indID = 1:numel(app.strat) %through each selected wedge
                id = app.strat(indID).ID; %store temp ID num
                if(id > numel(app.wedges)) %id is great than num of wedges
                    uialert(app.UIFigure, sprintf('Wedge %d not defined!',id),'Non-existant Wedge');
                    return; %GTFO pls
                end% if out of wedges bounds
                if(isempty(id))
                    continue; %skip this wedge
                end %if empty
                if(~ischar(app.wedges(id).Sector))
                    continue; %skip this wedge, idk, help me
                end %if not a char
                sectors = strsplit(app.wedges(id).Sector,','); %get cell of sectors
                for indSect = 1:numel(sectors) %through each sector
                    if(isfield(app.constraints, sectors{indSect})) %does the sector appear in constriants?
                        app.usedConst.(sectors{indSect}) =  ...
                            app.usedConst.(sectors{indSect}) + 1; %increment
                    end %if in constraints
                end %for sectors
            end %for id's
            pKids = []; %list of problem rows/children
            aler = ''; %char array of alerts
            for indCS = 1:numel(names) %loop through all fields
                if(app.usedConst.(names{indCS}) > app.constraints.(names{indCS}))
                    %more used than constraints
                    aler = [aler, sprintf('More %s wedges used than allowed! \n', names{indCS})];
                    pKids = [pKids indCS]; %add to keep track
                end %for 
            end %loop through fields
            outData = app.OutTable.Data; %pull data from the output table for count of ind. wedges
            pIds = []; %problematic IDs
            for indOut = 1:size(outData, 1) %through each row
                if(outData{indOut,4} > .5*app.tWedges.Value && app.tWedges.Value > 2)
                    pIds = [pIds outData{indOut,1}]; %add id to issue
                    aler = [aler, sprintf('Wedge %d is more than half your total wedges! \n', pIds(end))];
                end %if
            end %for data
            if(~isempty(aler)) %we have alerts
                aler = [aler sprintf('Please delete highlighted wedges until constriants are met.')];
                uialert(app.UIFigure,aler, 'Constraint Violations'); %display the alert
                app.GraphButton.Enable = 'off';
                app.anno(2) = 1;
            else %no errors
                app.GraphButton.Enable = 'on'; % we can graph!
            end %disp alert
            % loop through strat again to see which are problematic
            pRow = []; %strat rows with problems to highlight
            for indID = 1:numel(app.strat) %through each selected wedge
                id = app.strat(indID).ID; %store temp ID num
                if(any(app.strat(indID).ID == pIds)) % strat has issue filled ID
                    pRow = [pRow indID]; %add to thingy
                end %if ID is over used
                if(isempty(id))
                    continue; %skip this wedge
                end %if empty
                if(~ischar(app.wedges(id).Sector))
                    continue; %skip this wedge, idk, help me
                end %if not a char
                sectors = strsplit(app.wedges(id).Sector,','); %get cell of sectors
                for indSect = 1:numel(sectors) %through each sector
                    for indKid = 1:numel(pKids) %through each problem row
                        if(strcmp(names{pKids(indKid)}, sectors{indSect}))
                            pRow = [pRow indID]; %add to thingy
                        end %if
                    end %for kids
                    %%%%%%%%%%%%%%%% REEMBER WHICH ROWS OF STRAT ARE PROBLEMATIC
                end %for sectors
                
            end %for id's
            pRow = unique(pRow); %sort and eliminate dups
            colors = .94*ones(numel(app.strat),3); % make array for color highlighting
            colors(1:2:end,:) = 1; %set to white for striping
            colors(pRow,1) = 1; %RED
            colors(pRow, 2:3) = 0; %GB none
            app.StratTable.BackgroundColor = colors; %set the colors
            
            % if strategy years are in order, seems like a decent spot to put this
            app.anno(1) = 0; %no problem yet
            for indStr = 1:numel(app.strat)-1
                if(app.strat(indStr).YearStart > app.strat(indStr+1).YearStart)
                    app.anno(1) = 1; %we woke up and the apartment was on fire
                end %if out of order
            end %go through strats
            if(app.anno(1) == 1) %and we didn't even get out of bed
                app.GraphButton.Enable = 'off'; %can't graph
                app.sortStrat.BackgroundColor = 'green'; %put out the Fire!
            end % if can't graph now
            if(app.anno(1) == 0) %ok to move back to grey
                app.sortStrat.BackgroundColor = .96*[1 1 1]; %set back to grey
            end %if things aren't violated
            if(all(app.anno == 0)) %we all gucci bois & grils
                app.GraphButton.Enable = 'on'; %can graph
            end %if everything is good

        end
        
        function [] = graphWarming(app)
            %calculate and graph mean temperature change
            title(app.CO2WarmingGraph, 'Mean Warming');
            ylabel(app.CO2WarmingGraph, 'Change in Temperature (Δt)');
            xlabel(app.CO2WarmingGraph, 'Year');
            ylim(app.CO2WarmingGraph, [0,5]);
            x = 2000:1:2068;
            Temp_vals = zeros(size(app.CO2_vals));
            for index = 1:69
                Temp_vals(index) = app.lambda*5.35*log((app.CO2_vals(index)/2.1)/278);
            end
            plot(app.CO2WarmingGraph, x, Temp_vals, '-b');
        end
        
        function [] = FlatlinePrediction(app)
            try
                StartYear = app.strat(1).YearStart; %Checks if there is a strat loaded in
            catch
                StartYear = 2018;
            end
            if(isempty(StartYear))
                StartYear = 2018; %catch weird error from tables
            end %catchy
            app.StartYearEditField.Value = StartYear;
            y =  app.historical(:,2); %All the historical data years
            myPrediction = @(t) y(end) * 2.^((t-2017)/50); %Equation for predictions
            %For values ABOVE flat line, you need the same amount of wedges to flatline in any single upcoming year
            %Thus, this next prediction simplifies the calculation since you know that the ceiling of the difference 
            %between the prediction and the flat line will give you the exact number of wedges you need
            myPredictedValue = myPrediction(StartYear + 50); 
            CurrentFlatLineValue = myPrediction(StartYear); %the flat line of Start Year
            w = myPredictedValue - CurrentFlatLineValue; 
            if strcmp(app.FlatlineYear.Value, 'Current Year Levels')
                app.NumofWedgesFlatline.Value = ceil(w);                        
            elseif strcmp(app.FlatlineYear.Value, '1990 Levels')
                diff = CurrentFlatLineValue - y(32); %Difference between 1990 levels [y(32)] and Start Year flat line     
                slope = (2068-StartYear)/50; %Finds final slope of wedges lines at 2068
                app.NumofWedgesFlatline.Value = ceil(diff/slope + w); %the difference/slope gives you # wedges needed
            elseif strcmp(app.FlatlineYear.Value, '80% below of 1990 Levels')
                diff = CurrentFlatLineValue - .2* y(32);          
                slope = (2068-StartYear)/50;
                app.NumofWedgesFlatline.Value = ceil(diff/slope + w);
            end
        end

        function [] = sortStraticus(app)
        %sortStrat Sort strategies by yearstart
        %   Bubble sort, first by start year, then by ID
        
            m = numel(app.strat); %how many items
            swaps = 1; %preset
            while(swaps == 1) %while we doing stuff inside
                swaps = 0; %reset counter
                for indS = 1:m-1
                    try
                        if(app.strat(indS+1).YearStart < app.strat(indS).YearStart)
                            %out of order
                            temp = app.strat(indS); %temp fill
                            app.strat(indS) = app.strat(indS+1); %flip order
                            app.strat(indS+1) = temp; %fill it back up
                            swaps = 1; %we did a sort
                        elseif(app.strat(indS+1).YearStart == app.strat(indS).YearStart && ...
                                app.strat(indS+1).ID < app.strat(indS).ID)
                            %The id's are not in order
                            temp = app.strat(indS); %temp fill
                            app.strat(indS) = app.strat(indS+1); %flip order
                            app.strat(indS+1) = temp; %fill it back up
                            swaps = 1; %we did a sort
                        end %if sorter
                    catch
                        uialert(app.UIFigure, 'Check ID and Start Year!', 'Strategy');
                    end %try catch
                end %for each wedge
            end %while swapping
            toggleInputFields(app); %put back into
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            numFiles = size(varargin); %how many files? should be 1, 2, or 3
            if(numFiles > 0)%we have at least 1?
                readWedges(app,varargin{1}); %assign variables, assigns to prop inside
            end
            if(numFiles > 1) %we have at least 2
                readConstraints(app,varargin{2});
            end
            if(numFiles == 3) %we have three files
                app.strategy = readStrategy(app,varargin{3});
            end
            readHistoricalData(app);
            graphHistoricalData(app);
            graphCO2emissions(app)
            toggleInputFields(app);
            app.lambda = 0.7;
            
            %Code to test if structs fillign right
%             tempCell = struct2cell(app.wedges);
%             cell(:,:) = tempCell(:,1,:);
%             app.WedgeTable.Data = cell;
        end

        % Value changed function: ConstraintsTextFile
        function ConstraintsTextFileValueChanged(app, event)
            value = app.ConstraintsTextFile.Value;
            readConstraints(app,value);
            toggleInputFields(app);
                
        end

        % Value changed function: StrategyTextFile
        function StrategyTextFileValueChanged(app, event)
            value = app.StrategyTextFile.Value;
            readStrat(app,value);
            toggleInputFields(app);
            
        end

        % Callback function
        function WedgesTextFileValueChanged(app, event)
            value = app.WedgesTextFile.Value;
            readWedges(app,value);
            toggleInputFields(app);
        end

        % Button pushed function: wedgesBrowse
        function wedgesBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            filepath = [path file]; %total file
            if(file == 0) %exited out
                app.ErrorText.Value = app.fileCancel;
                app.ErrorText.FontColor = 'black';
            else
                readWedges(app,filepath);
                app.files{1} = file; %overwrite so it has the right filename
            end
            toggleInputFields(app);
        end

        % Button pushed function: constraintsBrowse
        function constraintsBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            filepath = [path file]; %total file
            if(file == 0) %exited out
                app.ErrorText.Value = app.fileCancel;
                app.ErrorText.FontColor = 'black';
            else
                readConstraints(app,filepath);
                app.files{2} = file;
            end
            toggleInputFields(app);
        end

        % Button pushed function: strategyBrowse
        function strategyBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            filepath = [path file]; %total file
            if(file == 0) %exited out
                app.ErrorText.Value = app.fileCancel;
                app.ErrorText.FontColor = 'black';
            else
                readStrat(app,filepath);
                app.files{3} = file;
            end
            toggleInputFields(app);
        end

        % Value changing function: ConstraintsTextFile
        function ConstraintsTextFileValueChanging(app, event)
            changingValue = event.Value;
            app.ConstraintsTextFile.FontColor = 'black';
        end

        % Value changing function: StrategyTextFile
        function StrategyTextFileValueChanging(app, event)
            changingValue = event.Value;
            app.StrategyTextFile.FontColor = 'black';
        end

        % Callback function
        function WedgesTextFileValueChanging(app, event)
            changingValue = event.Value;
            app.WedgesTextFile.FontColor = 'black';
        end

        % Button pushed function: wAddAbove
        function wAddAbovePushed(app, event)
            data = app.WedgeTable.Data;
            newRow = cell(1,app.dim(1)); %blank cell
            data = [data(1:app.selectedInd(1)-1,:); newRow; data(app.selectedInd(1):end,:)];
            app.WedgeTable.Data = data;
            app.wAddAbove.Enable = 'off';
            app.wDelete.Enable = 'off';
            app.AddWedge.Enable = 'off';
            app.AWYearStart.Enable = 'off';
            wedgesTableID(app);%reorder ID
            table2structi(app, 'Wedges'); %input data
            toggleInputFields(app); %deal with new data
        end

        % Button pushed function: wAddBelow
        function wAddBelowButtonPushed(app, event)
            if(strcmp(app.wAddAbove.Enable, 'off')) %no thing selected
                data = app.WedgeTable.Data;
                newRow = cell(1,app.dim(1)); %blank cell
                data = [data; newRow]; %add row below
                app.WedgeTable.Data = data;
                wedgesTableID(app);%reorder ID
                table2structi(app, 'Wedges'); %input data
                toggleInputFields(app); %deal with new data
            else
                data = app.WedgeTable.Data;
                newRow = cell(1,app.dim(1)); %blank cell
                data = [data(1:app.selectedInd(1),:); newRow; data(app.selectedInd(1)+1:end,:)];
                app.WedgeTable.Data = data;
                app.wAddAbove.Enable = 'off';
                app.wDelete.Enable = 'off';
                app.AddWedge.Enable = 'off';
                app.AWYearStart.Enable = 'off';
                wedgesTableID(app);%reorder ID
                table2structi(app, 'Wedges'); %input data
                toggleInputFields(app); %deal with new data
            end %if
        end

        % Button pushed function: wDelete
        function wDeleteButtonPushed(app, event)
            data = app.WedgeTable.Data;
            data = [data(1:app.selectedInd(1)-1,:); data(app.selectedInd(1)+1:end,:)];
            app.WedgeTable.Data = data;
            app.wAddAbove.Enable = 'off';
            app.wDelete.Enable = 'off';
            app.AddWedge.Enable = 'off';
            app.AWYearStart.Enable = 'off';
            wedgesTableID(app);%reorder ID
            table2structi(app, 'Wedges'); %input data
            toggleInputFields(app); %deal with new data
        end

        % Cell selection callback: WedgeTable
        function WedgeTableCellSelection(app, event)
            app.selectedInd = event.Indices;
            app.wAddAbove.Enable = 'on';
            app.wDelete.Enable = 'on';
            app.AddWedge.Enable = 'on';
            app.AWYearStart.Enable = 'on';
        end

        % Button pushed function: wSave
        function wSaveButtonPushed(app, event)
            save2File(app, 'Wedges');
        end

        % Cell edit callback: WedgeTable
        function WedgeTableCellEdit(app, event)
            table2structi(app, 'Wedges');
            toggleInputFields(app); %updates
        end

        % Button pushed function: cAddBelow
        function cAddBelowButtonPushed(app, event)
             if(strcmp(app.cAddAbove.Enable, 'off')) %no thing selected
                data = app.ConstraintsTable.Data;
                newRow = cell(1,app.dim(2)); %blank cell
                data = [data; newRow]; %add row below
                app.ConstraintsTable.Data = data;
                table2structi(app, 'Constraints'); %input data
                toggleInputFields(app); %deal with new data
            else
                data = app.ConstraintsTable.Data;
                newRow = cell(1,app.dim(2)); %blank cell
                data = [data(1:app.selectedInd(1),:); newRow; data(app.selectedInd(1)+1:end,:)];
                app.ConstraintsTable.Data = data;
                app.cAddAbove.Enable = 'off';
                app.cDelete.Enable = 'off';
                table2structi(app, 'Constraints'); %input data
                toggleInputFields(app); %deal with new data
            end %if
        end

        % Button pushed function: cAddAbove
        function cAddAboveButtonPushed(app, event)
            data = app.ConstraintsTable.Data;
            newRow = cell(1,app.dim(2)); %blank cell
            data = [data(1:app.selectedInd(1)-1,:); newRow; data(app.selectedInd(1):end,:)];
            app.ConstraintsTable.Data = data;
            app.cAddAbove.Enable = 'off';
            app.cDelete.Enable = 'off';
            table2structi(app, 'Constraints'); %input data
            toggleInputFields(app); %deal with new data
        end

        % Button pushed function: cDelete
        function cDeleteButtonPushed(app, event)
            data = app.ConstraintsTable.Data;
            data = [data(1:app.selectedInd(1)-1,:); data(app.selectedInd(1)+1:end,:)];
            app.ConstraintsTable.Data = data;
            app.cAddAbove.Enable = 'off';
            app.cDelete.Enable = 'off';
            table2structi(app, 'Constraints'); %input data
            toggleInputFields(app); %deal with new data
        end

        % Cell selection callback: ConstraintsTable
        function ConstraintsTableCellSelection(app, event)
            app.selectedInd = event.Indices;
            app.cAddAbove.Enable = 'on';
            app.cDelete.Enable = 'on';
        end

        % Cell edit callback: ConstraintsTable
        function ConstraintsTableCellEdit(app, event)
            table2structi(app, 'Constraints');
            toggleInputFields(app); %updates
        end

        % Button pushed function: cSave
        function cSaveButtonPushed(app, event)
            save2File(app, 'Constraints');
        end

        % Cell selection callback: StratTable
        function StratTableCellSelection(app, event)
            app.selectedInd = event.Indices;
            app.sAddAbove.Enable = 'on';
            app.sDelete.Enable = 'on';
            try
                id = app.strat(app.selectedInd(1)).ID; %which ID
                wedgeInfo = sprintf('Wedge ID: %d \nStrategy Name: %s \nDescription: %s \nNeeds: %s \nChallenges: %s\n', ...
                    id, app.wedges(id).StrategyName, app.wedges(id).Description, app.wedges(id).Needs, app.wedges(id).Challenges);
                app.wInfo.Value = wedgeInfo; %fill it up
            catch
                app.wInfo.Value = ''; %failsafe
            end %try catch, i'm getting lazy
        end

        % Cell edit callback: StratTable
        function StratTableCellEdit(app, event)
            table2structi(app, 'Strategy');
            toggleInputFields(app); %updates
        end

        % Button pushed function: sAddAbove
        function sAddAboveButtonPushed(app, event)
            data = app.StratTable.Data;
            newRow = cell(1,app.dim(3)); %blank cell
            data = [data(1:app.selectedInd(1)-1,:); newRow; data(app.selectedInd(1):end,:)];
            app.StratTable.Data = data;
            app.sAddAbove.Enable = 'off';
            app.sDelete.Enable = 'off';
            table2structi(app, 'Strategy'); %input data
            toggleInputFields(app); %deal with new data
        end

        % Button pushed function: sAddBelow
        function sAddBelowButtonPushed(app, event)
            if(strcmp(app.sAddAbove.Enable, 'off')) %no thing selected
                data = app.StratTable.Data;
                newRow = cell(1,app.dim(3)); %blank cell
                data = [data; newRow]; %add row below
                app.StratTable.Data = data;
                table2structi(app, 'Strategy'); %input data
                toggleInputFields(app); %deal with new data
            else
                data = app.StratTable.Data;
                newRow = cell(1,app.dim(3)); %blank cell
                data = [data(1:app.selectedInd(1),:); newRow; data(app.selectedInd(1)+1:end,:)];
                app.StratTable.Data = data;
                app.sAddAbove.Enable = 'off';
                app.sDelete.Enable = 'off';
                table2structi(app, 'Strategy'); %input data
                toggleInputFields(app); %deal with new data
            end %if
        end

        % Button pushed function: sDelete
        function sDeleteButtonPushed(app, event)
            data = app.StratTable.Data;
            data = [data(1:app.selectedInd(1)-1,:); data(app.selectedInd(1)+1:end,:)];
            app.StratTable.Data = data;
            app.sAddAbove.Enable = 'off';
            app.sDelete.Enable = 'off';
            table2structi(app, 'Strategy'); %input data
            toggleInputFields(app); %deal with new data
        end

        % Button pushed function: sSave
        function sSaveButtonPushed(app, event)
            save2File(app, 'Strategy');
        end

        % Button pushed function: oSave
        function oSaveButtonPushed(app, event)
            save2File(app, 'Output');
        end

        % Value changed function: GraphSwitch
        function GraphSwitchValueChanged(app, event)
            value = app.GraphSwitch.Value;
            if (strcmp(value, 'CO2 Concentrations'))
                app.graphCO2emissions;
                app.LambdaValue.Enable = 'off';
            else
                app.graphWarming;
                app.LambdaValue.Enable = 'on';
            end
        end

        % Callback function
        function NumWedgesChanged(app, event)
            value = app.NumberofWedgesEditField.Value; 
            if class(value) ~= 'double'
                return
            end
            clf(app.EmissionsGraph);
            graphHistoricalData(app);          
        end

        % Callback function
        function YearStartChanged(app, event)
            data = app.YearStartTable;
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
%             clf(app.EmissionsGraph); %this created an extra fig
            try
                app.graphHistoricalData
                app.graphCO2emissions
            catch
                uialert(app.UIFigure, 'Check ID and YearStart', 'Strategy Table');
            end
        end

        % Button pushed function: AddWedge
        function AddWedgeButtonPushed(app, event)
            if(numel(app.strat) == 1 && ~isfield(app.strat, 'ID')) %does it exist?
                app.strat(1).ID = app.selectedInd(1); %row value
                app.strat(1).YearStart = app.AWYearStart.Value; %year start val;
            else
                app.strat(end+1).ID = app.selectedInd(1); %row value
                app.strat(end).YearStart = app.AWYearStart.Value; %year start val;
            end %if
            app.files{3} = 'From Table'; %set origin
            toggleInputFields(app); %display it
        end

        % Value changed function: LambdaValue
        function LambdaValueValueChanged(app, event)
            value = app.LambdaValue.Value;
            app.lambda = value;
            app.graphWarming
        end

        % Value changed function: FlatlineYear
        function FlatlineYearChanged(app, event)
            value = app.FlatlineYear.Value;
            FlatlinePrediction(app);
        end

        % Button pushed function: sortStrat
        function sortStratButtonPushed(app, event)
            sortStraticus(app); %sort the strategy!
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.dataInputTab = uitabgroup(app.UIFigure);
            app.Files = uitab(app.dataInputTab);
            app.Files.Title = 'Files';
            app.ConstraintsTextFile = uieditfield(app.Files, 'text');
            app.ConstraintsTextFile.ValueChangedFcn = createCallbackFcn(app, @ConstraintsTextFileValueChanged, true);
            app.ConstraintsTextFile.ValueChangingFcn = createCallbackFcn(app, @ConstraintsTextFileValueChanging, true);
            app.StrategyTextFile = uieditfield(app.Files, 'text');
            app.StrategyTextFile.ValueChangedFcn = createCallbackFcn(app, @StrategyTextFileValueChanged, true);
            app.StrategyTextFile.ValueChangingFcn = createCallbackFcn(app, @StrategyTextFileValueChanging, true);
            app.ConstraintsTextFileLabel = uilabel(app.Files);
            app.ConstraintsTextFileLabel.Text = 'Constraints Text File';
            app.StrategyTextFileEditFieldLabel = uilabel(app.Files);
            app.StrategyTextFileEditFieldLabel.Text = 'Strategy Text File';
            app.ErrorText = uitextarea(app.Files);
            app.ErrorText.Editable = 'off';
            app.WedgesTextLamp = uilamp(app.Files);
            app.ConstraintsTextLamp = uilamp(app.Files);
            app.StrategyTextLamp = uilamp(app.Files);
            app.wedgesBrowse = uibutton(app.Files, 'push');
            app.wedgesBrowse.ButtonPushedFcn = createCallbackFcn(app, @wedgesBrowseButtonPushed, true);
            app.wedgesBrowse.Text = 'Browse...';
            app.constraintsBrowse = uibutton(app.Files, 'push');
            app.constraintsBrowse.ButtonPushedFcn = createCallbackFcn(app, @constraintsBrowseButtonPushed, true);
            app.constraintsBrowse.Text = 'Browse...';
            app.strategyBrowse = uibutton(app.Files, 'push');
            app.strategyBrowse.ButtonPushedFcn = createCallbackFcn(app, @strategyBrowseButtonPushed, true);
            app.strategyBrowse.Text = 'Browse...';
            app.titleLabel = uilabel(app.Files);
            app.titleLabel.Text = {'Please input the wedges,'; 'constraint, and strategy info.'};
            app.descriptionLabel = uilabel(app.Files);
            app.descriptionLabel.Text = {'You can enter the file names or file'; 'paths here, or enter data manually'; 'into the tables on the other tabs.  If'; 'you have already entered data, the'; 'entry position will be greyed out. '; 'Simply reuse the ''Browse'' button'; 'to enter new data.'};
            app.WedgesTextFileEditFieldLabel = uilabel(app.Files);
            app.WedgesTextFileEditFieldLabel.Text = 'Wedges Text File';
            app.WedgesTextFile = uieditfield(app.Files, 'text');
            app.EmissionsGraphTab = uitab(app.dataInputTab);
            app.EmissionsGraphTab.Title = 'Emissions Graph';
            app.TotalCostEditFieldLabel_2 = uilabel(app.EmissionsGraphTab);
            app.TotalCostEditFieldLabel_2.Text = 'Total Cost';
            app.tCost = uieditfield(app.EmissionsGraphTab, 'numeric');
            app.tCost.Editable = 'off';
            app.TotalWedgesUsedEditFieldLabel = uilabel(app.EmissionsGraphTab);
            app.TotalWedgesUsedEditFieldLabel.Text = 'Total Wedges Used';
            app.tWedges = uieditfield(app.EmissionsGraphTab, 'numeric');
            app.tWedges.Editable = 'off';
            app.NumberofWedgestoFlatlineatDropDownLabel = uilabel(app.EmissionsGraphTab);
            app.NumberofWedgestoFlatlineatDropDownLabel.Text = 'Number of Wedges to Flatline at';
            app.FlatlineYear = uidropdown(app.EmissionsGraphTab);
            app.FlatlineYear.Items = {'Current Year Levels', '1990 Levels', '80% below of 1990 Levels'};
            app.FlatlineYear.ValueChangedFcn = createCallbackFcn(app, @FlatlineYearChanged, true);
            app.FlatlineYear.Value = 'Current Year Levels';
            app.NumofWedgesFlatline = uieditfield(app.EmissionsGraphTab, 'numeric');
            app.NumofWedgesFlatline.Editable = 'off';
            app.NumofWedgesFlatline.Value = 11;
            app.inStartYearLabel = uilabel(app.EmissionsGraphTab);
            app.inStartYearLabel.Text = 'in Start Year';
            app.StartYearEditField = uieditfield(app.EmissionsGraphTab, 'numeric');
            app.StartYearEditField.Editable = 'off';
            app.StartYearEditField.Value = 2018;
            app.EmissionsGraph = uiaxes(app.EmissionsGraphTab);
            title(app.EmissionsGraph, 'Title')
            xlabel(app.EmissionsGraph, 'X')
            ylabel(app.EmissionsGraph, 'Y')
            app.CO2WarmingTab = uitab(app.dataInputTab);
            app.CO2WarmingTab.Title = 'CO2 & Warming';
            app.GraphSwitch = uiswitch(app.CO2WarmingTab, 'slider');
            app.GraphSwitch.Items = {'CO2 Concentrations', 'Mean Warming'};
            app.GraphSwitch.ValueChangedFcn = createCallbackFcn(app, @GraphSwitchValueChanged, true);
            app.GraphSwitch.Value = 'CO2 Concentrations';
            app.Label = uilabel(app.CO2WarmingTab);
            app.Label.Enable = 'off';
            app.Label.Text = 'λ';
            app.LambdaValue = uislider(app.CO2WarmingTab);
            app.LambdaValue.Limits = [0.5 1];
            app.LambdaValue.ValueChangedFcn = createCallbackFcn(app, @LambdaValueValueChanged, true);
            app.LambdaValue.Enable = 'off';
            app.LambdaValue.Value = 0.7;
            app.CO2WarmingGraph = uiaxes(app.CO2WarmingTab);
            title(app.CO2WarmingGraph, 'Title')
            xlabel(app.CO2WarmingGraph, 'X')
            ylabel(app.CO2WarmingGraph, 'Y')
            app.WedgesByTable = uitab(app.dataInputTab);
            app.WedgesByTable.Title = 'Wedges Table';
            app.WedgeTable = uitable(app.WedgesByTable);
            app.WedgeTable.ColumnName = {'ID'; 'Category'; 'StrategyName'; 'Sector'; 'Description'; 'Needs'; 'Challenges'; 'Cost'};
            app.WedgeTable.RowName = {};
            app.WedgeTable.ColumnEditable = true;
            app.WedgeTable.CellEditCallback = createCallbackFcn(app, @WedgeTableCellEdit, true);
            app.WedgeTable.CellSelectionCallback = createCallbackFcn(app, @WedgeTableCellSelection, true);
            app.wAddAbove = uibutton(app.WedgesByTable, 'push');
            app.wAddAbove.ButtonPushedFcn = createCallbackFcn(app, @wAddAbovePushed, true);
            app.wAddAbove.Enable = 'off';
            app.wAddAbove.Text = 'Add Row Above';
            app.wSave = uibutton(app.WedgesByTable, 'push');
            app.wSave.ButtonPushedFcn = createCallbackFcn(app, @wSaveButtonPushed, true);
            app.wSave.Text = 'Save to File';
            app.wDelete = uibutton(app.WedgesByTable, 'push');
            app.wDelete.ButtonPushedFcn = createCallbackFcn(app, @wDeleteButtonPushed, true);
            app.wDelete.Enable = 'off';
            app.wDelete.Text = 'Delete Row';
            app.wAddBelow = uibutton(app.WedgesByTable, 'push');
            app.wAddBelow.ButtonPushedFcn = createCallbackFcn(app, @wAddBelowButtonPushed, true);
            app.wAddBelow.Text = 'Add Row Below';
            app.AddWedge = uibutton(app.WedgesByTable, 'push');
            app.AddWedge.ButtonPushedFcn = createCallbackFcn(app, @AddWedgeButtonPushed, true);
            app.AddWedge.Enable = 'off';
            app.AddWedge.Text = 'Add Selected Wedge to Strategy';
            app.StartinginYearEditFieldLabel = uilabel(app.WedgesByTable);
            app.StartinginYearEditFieldLabel.Text = 'Starting in Year:';
            app.AWYearStart = uieditfield(app.WedgesByTable, 'numeric');
            app.AWYearStart.Limits = [2018 2068];
            app.AWYearStart.ValueDisplayFormat = '%.0f';
            app.AWYearStart.Enable = 'off';
            app.AWYearStart.Value = 2018;
            app.StrategyByTable = uitab(app.dataInputTab);
            app.StrategyByTable.Title = 'Pick Wedges';
            app.StratTable = uitable(app.StrategyByTable);
            app.StratTable.ColumnName = {'ID'; 'YearStart'};
            app.StratTable.RowName = {};
            app.StratTable.ColumnEditable = true;
            app.StratTable.CellEditCallback = createCallbackFcn(app, @StratTableCellEdit, true);
            app.StratTable.CellSelectionCallback = createCallbackFcn(app, @StratTableCellSelection, true);
            app.sAddAbove = uibutton(app.StrategyByTable, 'push');
            app.sAddAbove.ButtonPushedFcn = createCallbackFcn(app, @sAddAboveButtonPushed, true);
            app.sAddAbove.Enable = 'off';
            app.sAddAbove.Text = 'Add Row Above';
            app.sAddBelow = uibutton(app.StrategyByTable, 'push');
            app.sAddBelow.ButtonPushedFcn = createCallbackFcn(app, @sAddBelowButtonPushed, true);
            app.sAddBelow.Text = 'Add Row Below';
            app.sDelete = uibutton(app.StrategyByTable, 'push');
            app.sDelete.ButtonPushedFcn = createCallbackFcn(app, @sDeleteButtonPushed, true);
            app.sDelete.Enable = 'off';
            app.sDelete.Text = 'Delete Row';
            app.sSave = uibutton(app.StrategyByTable, 'push');
            app.sSave.ButtonPushedFcn = createCallbackFcn(app, @sSaveButtonPushed, true);
            app.sSave.Text = 'Save to File';
            app.ConstraintsTable = uitable(app.StrategyByTable);
            app.ConstraintsTable.ColumnName = {'Sector'; 'Constraints'; 'NumUsed'};
            app.ConstraintsTable.RowName = {};
            app.ConstraintsTable.ColumnEditable = [true true false];
            app.ConstraintsTable.CellEditCallback = createCallbackFcn(app, @ConstraintsTableCellEdit, true);
            app.ConstraintsTable.CellSelectionCallback = createCallbackFcn(app, @ConstraintsTableCellSelection, true);
            app.cAddAbove = uibutton(app.StrategyByTable, 'push');
            app.cAddAbove.ButtonPushedFcn = createCallbackFcn(app, @cAddAboveButtonPushed, true);
            app.cAddAbove.Enable = 'off';
            app.cAddAbove.Text = 'Add Row Above';
            app.cAddBelow = uibutton(app.StrategyByTable, 'push');
            app.cAddBelow.ButtonPushedFcn = createCallbackFcn(app, @cAddBelowButtonPushed, true);
            app.cAddBelow.Text = 'Add Row Below';
            app.cDelete = uibutton(app.StrategyByTable, 'push');
            app.cDelete.ButtonPushedFcn = createCallbackFcn(app, @cDeleteButtonPushed, true);
            app.cDelete.Enable = 'off';
            app.cDelete.Text = 'Delete Row';
            app.cSave = uibutton(app.StrategyByTable, 'push');
            app.cSave.ButtonPushedFcn = createCallbackFcn(app, @cSaveButtonPushed, true);
            app.cSave.Text = 'Save to File';
            app.ConstraintsLabel = uilabel(app.StrategyByTable);
            app.ConstraintsLabel.Text = 'Constraints';
            app.StrategyLabel = uilabel(app.StrategyByTable);
            app.StrategyLabel.Text = 'Strategy';
            app.GraphButton = uibutton(app.StrategyByTable, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.sortStrat = uibutton(app.StrategyByTable, 'push');
            app.sortStrat.ButtonPushedFcn = createCallbackFcn(app, @sortStratButtonPushed, true);
            app.sortStrat.Text = {'Sort Wedges by'; 'Start Year'};
            app.plsSelect = uilabel(app.StrategyByTable);
            app.plsSelect.Text = 'Select a Strategy Cell for information';
            app.wInfo = uitextarea(app.StrategyByTable);
            app.wInfo.Editable = 'off';
            app.OuputTable = uitab(app.dataInputTab);
            app.OuputTable.Title = 'Ouput Table';
            app.OutTable = uitable(app.OuputTable);
            app.OutTable.ColumnName = {'ID'; 'StrategyName'; 'Sector'; 'NumUsed'; 'TotalCost'; 'Challenges'};
            app.OutTable.RowName = {};
            app.PlsLoadLabel = uilabel(app.OuputTable);
            app.PlsLoadLabel.Text = 'Please load Wedges and Strategy data. ';
            app.oSave = uibutton(app.OuputTable, 'push');
            app.oSave.ButtonPushedFcn = createCallbackFcn(app, @oSaveButtonPushed, true);
            app.oSave.Text = 'Save to File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end